classdef FINAL_PROJECT_Ben_Cohen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SilentEnablerSwitch            matlab.ui.control.Switch
        ShowsilentperiodofthegivensignalLabel_2  matlab.ui.control.Label
        seconds2                       matlab.ui.control.Label
        seconds1                       matlab.ui.control.Label
        seconds3                       matlab.ui.control.Label
        TotalStableTimeEditField       matlab.ui.control.NumericEditField
        TotalStableTimeEditFieldLabel  matlab.ui.control.Label
        TotalActionTimeEditField       matlab.ui.control.NumericEditField
        TotalActionTimeEditFieldLabel  matlab.ui.control.Label
        TotalSampleTimeEditField       matlab.ui.control.NumericEditField
        TotalSampleTimeLabel           matlab.ui.control.Label
        LoadFileLabel                  matlab.ui.control.Label
        LoadFileButton                 matlab.ui.control.Button
        ActiveEnablerSwitch            matlab.ui.control.Switch
        ShowsilentperiodofthegivensignalLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        data    % Data from a loaded file.
        t   % Independent variable - time vector converted from the matlab format (timestamp).
        x   % Creating the proper x vector for the plot later on. not neccessary but easier to use.
        y   % Creating the proper y vector for the plot later on. not neccessary but easier to use.
        z   % Creating the proper z vector for the plot later on. not neccessary but easier to use.
        TotalAccel % Calculating the AVG acceleration
        tmpvec0 % Temporary Vector used to calculate the active times of the signal.
        tmpvec1 % Temporary Vector used to calculate the silent times of the signal.
        calc_silent % A vector that hold the final result for the silent time.
        calc_active % A vector that hold the final result for the active time.
        silent_handler % Graph TMP1 pointer. Used for active button
        active_handler % Graph TMP2 pointer. Used for silent button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [file,path] = uigetfile ('*.mat');
            if isequal(file,0)
                disp('No files selected. Please select a file.')
            else
                disp(['You have selected ', fullfile(path,file)]);
            end
            app.data = load(fullfile(path,file));               % Loading a file by choice.
            timestamp = app.data.Acceleration.Timestamp;        % Asserting all matlab-created variables into an easier variables to use.
            app.x = app.data.Acceleration.X;
            app.y = app.data.Acceleration.Y;
            app.z = app.data.Acceleration.Z;
            app.t = zeros(size(timestamp));                     %Converting the 'Timestamp' format -> to a vector that hold time calculated in seconds.
            for n = 1 : length(timestamp)
                app.t(n) = seconds(timestamp(n) - timestamp(1));
            end
            app.TotalAccel = sqrt(app.x.^2+app.y.^2+app.z.^2);                  % Calculating the Total Acceleration.
            app.TotalSampleTimeEditField.Value = app.t(end);                    % Setting the GUI to show the length of the signal, in seconds.
            app.TotalActionTimeEditField.Value = sum(abs(app.TotalAccel-9.8)> 0.2)*(app.t(2)-app.t(1));             %Settings the GUI to show the length of the action time.
            app.TotalStableTimeEditField.Value = sum(abs(app.TotalAccel-9.8) < 0.6)*(app.t(2)-app.t(1));            %Settings the GUI to show the length of the silent time.
            plot(app.UIAxes, app.t, app.TotalAccel, 'r');
            app.UIAxes.Visible = true;                                          % Fixes plotting in AppDesigner creating a new empty figure.
            hold(app.UIAxes, 'on');                                              
            set(app.UIAxes, 'XTickMode', 'auto', 'XTickLabelMode', 'auto');
            set(app.UIAxes, 'YTickMode', 'auto', 'YTickLabelMode', 'auto');
            set(get(app.UIAxes,'Ylabel'),'String','Acceleration (m/s^2)')       % Labeling 'Y' appropriately.
            set(get(app.UIAxes,'Xlabel'),'String','Time(s)')                    % Labeling 'X' the graph appropriately.
            app.UIAxes.XLim = [0 app.t(end)];                                   % Setting the boundaries of the figure to show the entire signal - from the start to the end of the sample.
   
        end

        % Value changed function: ActiveEnablerSwitch
        function ActiveEnablerSwitchValueChanged(app, event)
            value = app.ActiveEnablerSwitch.Value;
            if (value==1)
            app.tmpvec0 = abs(app.TotalAccel-9.8) < 0.6;                       % Calculating 
            a_win_length = 30;                                                 % Asserting a window in the size of 30 sample.
            a_threshold = 25;                                                  % Fitting the threshold to the type of the signal.
            app.calc_active = [];
            for pos = 1:length(app.tmpvec0)-a_win_length                                            % A loop starting at at the beggining untill the last possible placement for the set window.
                app.calc_active(pos) = sum(app.tmpvec0(pos:(pos+a_win_length))) > a_threshold;      % Summing all the activity in the current window.    
            end
            app.calc_active = [app.calc_active  zeros(1,a_win_length)];                             % Padding the calc_active vector.
            app.active_handler = plot(app.UIAxes, app.t(find(app.calc_active==0)), (app.calc_active(find(app.calc_active==0))+9.8), 'om');          % Saving the plot's handler in order to control the different switch options
            else
            app.active_handler.Visible = 0;                                    % Setting the switch to show nothing when the user sets it to be 0.
            end
          
        end

        % Value changed function: SilentEnablerSwitch
        function SilentEnablerSwitchValueChanged(app, event)
            value = app.SilentEnablerSwitch.Value;
            if (value==1)
            app.tmpvec1 = abs(app.TotalAccel-9.8)> 0.2;                      % Calculating a logical vector that returns the places where there are changes that we should account for.
            s_win_length = 30;                                               % Asserting a window in the size of 30 sample.
            s_threshold = 15;                                                % Fitting the threshold to the type of the signal.
            app.calc_silent = [];
            for pos = 1:length(app.tmpvec1)-s_win_length                     % A loop starting at at the beggining untill the last possible placement for the set window.
                app.calc_silent(pos) = sum(app.tmpvec1(pos:(pos+s_win_length))) > s_threshold;     % Summing all the silent parts in the current window.
            end
            app.calc_silent = [app.calc_silent  zeros(1,s_win_length)];         % Padding the calc_active vector.
            app.silent_handler = plot(app.UIAxes, app.t(find(app.calc_silent==0)), (app.calc_silent(find(app.calc_silent==0))+9.8), 'ob');          % Saving the plot's handler in order to control the different switch options
            else
            app.silent_handler.Visible = 0;                                  % Setting the switch to show nothing when the user sets it to be 0.
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Accelerometer')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Acceleration (m/s^2)')
            zlabel(app.UIAxes, 'Z')
            subtitle(app.UIAxes, 'Acceleration on every given moment measured by a mobile device acceleration sensor.')
            app.UIAxes.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes.YTickLabel = {'-10'; '-9'; '-8'; '-7'; '-6'; '-5'; '-4'; '-3'; '-2'; '-1'; '0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.ShowsilentperiodofthegivensignalLabel = uilabel(app.UIFigure);
            app.ShowsilentperiodofthegivensignalLabel.Text = 'Show active periods';
            app.ActiveEnablerSwitch = uiswitch(app.UIFigure, 'slider');
            app.ActiveEnablerSwitch.ItemsData = [0 1];
            app.ActiveEnablerSwitch.ValueChangedFcn = createCallbackFcn(app, @ActiveEnablerSwitchValueChanged, true);
            app.ActiveEnablerSwitch.Value = 0;
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.LoadFileLabel = uilabel(app.UIFigure);
            app.LoadFileLabel.Text = 'To start to app, please load a file.';
            app.TotalSampleTimeLabel = uilabel(app.UIFigure);
            app.TotalSampleTimeLabel.Text = 'Total Sample Time  :';
            app.TotalSampleTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalSampleTimeEditField.Editable = 'off';
            app.TotalActionTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalActionTimeEditFieldLabel.Text = 'Total Action Time :';
            app.TotalActionTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalActionTimeEditField.Editable = 'off';
            app.TotalStableTimeEditFieldLabel = uilabel(app.UIFigure);
            app.TotalStableTimeEditFieldLabel.Text = 'Total Stable Time :';
            app.TotalStableTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.TotalStableTimeEditField.Editable = 'off';
            app.seconds3 = uilabel(app.UIFigure);
            app.seconds3.Text = '(s)';
            app.seconds1 = uilabel(app.UIFigure);
            app.seconds1.Text = '(s)';
            app.seconds2 = uilabel(app.UIFigure);
            app.seconds2.Text = '(s)';
            app.ShowsilentperiodofthegivensignalLabel_2 = uilabel(app.UIFigure);
            app.ShowsilentperiodofthegivensignalLabel_2.Text = 'Show silent periods';
            app.SilentEnablerSwitch = uiswitch(app.UIFigure, 'slider');
            app.SilentEnablerSwitch.ItemsData = [0 1];
            app.SilentEnablerSwitch.ValueChangedFcn = createCallbackFcn(app, @SilentEnablerSwitchValueChanged, true);
            app.SilentEnablerSwitch.Value = 0;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FINAL_PROJECT_Ben_Cohen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end